% description: The graphical user interface (GUI) reads OME-TIFF predicted maps from QuPath using Bio-Formats and maps the minerals (and colour legend) within the original project into plain images that
% can be opened elsewhere. Future updates include a Pythonic implementation for whole-slide images.
% Created: 20-Aug-24, Marco Acevedo Zamora
% Updated: 26-Nov-24, 12-Mar-25, 7-Nov-25, M.A.
% Scientific citation:
% Publication: https://doi.org/10.3390/min13020156
% Code repository: https://github.com/marcoaaz/Acevedo-Kamber/tree/main/QuPath_generatingMaps
classdef phase_interpreter_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Phaseinterpreterv1UIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ChoosetheanalysisPanel         matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        TrialtagEditField              matlab.ui.control.EditField
        TrialtagEditFieldLabel         matlab.ui.control.Label
        ConnectivityDropDown           matlab.ui.control.DropDown
        ConnectivityDropDownLabel      matlab.ui.control.Label
        TopmeshpxEditField             matlab.ui.control.NumericEditField
        TopmeshpxEditFieldLabel        matlab.ui.control.Label
        GenerateListBox                matlab.ui.control.ListBox
        GenerateListBoxLabel           matlab.ui.control.Label
        PixelcalibrationmpxEditField   matlab.ui.control.NumericEditField
        PixelcalibrationmpxEditFieldLabel  matlab.ui.control.Label
        SearchradiuspxEditField        matlab.ui.control.NumericEditField
        SearchradiuspxEditFieldLabel   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        ThegraphicaluserinterfaceisanupdateofthesoftwareLabel  matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        Citationhttpsdoiorg103390min13020156Label  matlab.ui.control.Label
        CustomisephasemapPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ClearROIButton                 matlab.ui.control.Button
        TypeofROIDropDown              matlab.ui.control.DropDown
        TypeofROIDropDownLabel         matlab.ui.control.Label
        RegionofinterestLabel          matlab.ui.control.Label
        DrawButton                     matlab.ui.control.Button
        TargetsLabel                   matlab.ui.control.Label
        RenamemineralsEditField_2      matlab.ui.control.EditField
        CustomnamesLabel               matlab.ui.control.Label
        OriginalsrankedLabel           matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        RenamemineralsEditField        matlab.ui.control.EditField
        DefinemapPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        MaskdilationDropDown           matlab.ui.control.DropDown
        MaskdilationDropDownLabel      matlab.ui.control.Label
        MaprotationDropDown            matlab.ui.control.DropDown
        MaprotationDropDownLabel       matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        MirrorhorizontallyCheckBox     matlab.ui.control.CheckBox
        BackgroundclassEditField       matlab.ui.control.EditField
        BackgroundclassEditFieldLabel  matlab.ui.control.Label
        TrainedclassifierEditField     matlab.ui.control.EditField
        TrainedclassifierEditFieldLabel  matlab.ui.control.Label
        QuPathprojectEditField         matlab.ui.control.EditField
        QuPathprojectEditFieldLabel    matlab.ui.control.Label
        GeneratepreviewButton          matlab.ui.control.Button
    end

    
    properties (Access = public)

        data_destinationDir %description
        data_phasemap1
        data_tablerank1
        data_phasemap_colour
        data_selected_targets0
        data_ROI_mask
        data_pos_ROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if ~isdeployed
                %Dependencies
                scriptsMarco = 'E:\Alienware_March 22\scripts_Marco\updated MatLab scripts\';            
                script_path = fullfile(scriptsMarco, 'quPath bridge');
    
                addpath(script_path);
                addpath(fullfile(scriptsMarco, 'bfmatlab'));
                addpath(fullfile(scriptsMarco, 'external_package\rgb'));
                cd(script_path)
            end
            format compact %displaying list

            % Initialize an app property           

            app.QuPathprojectEditField.Value = 'E:\Alienware_March 22\current work\rodrigo work\UHP32_benchmark\registration_11-Apr-24\registered\8bit\stack\qupath trial_14-Oct-25'; %qupath project
            app.TrainedclassifierEditField.Value = 'run_s10-hrMaps'; %*.ome.tif saved from QuPath
            app.BackgroundclassEditField.Value = 'hole';

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Open folder selection dialog
            selectedFolder = uigetdir(); 
            
            % Check if the user selected a folder or cancelled
            if selectedFolder ~= 0 % 0 indicates cancellation                
                app.QuPathprojectEditField.Value = string(selectedFolder);                 
            else                
                disp('Folder selection cancelled.');
            end
        end

        % Button pushed function: GeneratepreviewButton
        function GeneratepreviewButtonPushed(app, event)
            %User input:

            rootFolder = app.QuPathprojectEditField.Value; %qupath project
            classifierName = app.TrainedclassifierEditField.Value; %*.ome.tif saved from QuPath
            class_bg_txt = app.BackgroundclassEditField.Value; %classes to ignore, if not found (uses all)
            kernel_size = app.MaskdilationDropDown.Value;
            rot_angle = app.MaprotationDropDown.Value; %rotation angle (counter-clockwise)
            action_mirror = app.MirrorhorizontallyCheckBox.Value; %horizontal flip                                    
                                 
            %defaults         
            suffix = '.ome.tif';
            class_bg = {""};
            condition1 = isempty(class_bg_txt); %0x0 char                        
            
            if ~condition1
                class_bg_txt1 = regexprep(class_bg_txt, '\s*', '');
                class_bg = strsplit(class_bg_txt1, ',');
            end                         

            phasemap_inputName = strcat(classifierName, suffix);%default

            [minerals, triplet, destinationDir] = read_qupath_metadata(phasemap_inputName, suffix, rootFolder);

            [phasemap_fg, section_mask, tablerank] = read_qupath_map(phasemap_inputName, class_bg, minerals, triplet, ...
                kernel_size, destinationDir);            

            [phasemap, section_mask1] = transform_map(phasemap_fg, section_mask, rot_angle, action_mirror);
            imwrite(section_mask1, fullfile(destinationDir, 'sectionMask.tif')); %explains selection

            [tablerank1, phasemap1] = edit_phasemap(phasemap, tablerank, destinationDir);

            tablerank1 = readtable(fullfile(destinationDir, 'species.xlsx'), 'Sheet', 'phaseMap');
            selected_targets0 = tablerank1.Mineral';
            
            %Generate preview
            [phasemap_colour, tablerank2] = phasemap_rgb(phasemap1, tablerank1, selected_targets0, destinationDir);
            plot_phasemap(phasemap_colour, tablerank2, destinationDir)
            plotLogHistogramH(tablerank2, destinationDir)
            plotLogHistogramH2(tablerank2, destinationDir)

            %Default ROI (same as Clear ROI)  
            dim = size(phasemap_colour);
            ROI_mask = ones(dim);
            pos_ROI = floor([1, 1, dim(1), dim(2)]);            
         
            %Send through app
            app.data_destinationDir = destinationDir;
            app.data_phasemap1 = phasemap1;
            app.data_tablerank1 = tablerank1;
            app.data_phasemap_colour = phasemap_colour;
            app.TextArea.Value = strjoin(selected_targets0, ', ');
            app.RenamemineralsEditField.Value = strjoin(selected_targets0, ', '); %for practicity
            app.data_ROI_mask = ROI_mask;
            app.data_pos_ROI = pos_ROI;

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            
            mask_type = app.TypeofROIDropDown.Value;
            phasemap_colour = app.data_phasemap_colour;
            tablerank1 = app.data_tablerank1;

            %Draw ROI  
            [ROI_mask, pos_ROI] = mapping_ROI_selection(phasemap_colour, tablerank1, mask_type);           

            app.data_ROI_mask = ROI_mask;
            app.data_pos_ROI = pos_ROI;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            close all %previous plots

            %Data from app
            destinationDir = app.data_destinationDir;
            phasemap1 = app.data_phasemap1;
            tablerank1 = app.data_tablerank1;
            ROI_mask = app.data_ROI_mask;
            pos_ROI = app.data_pos_ROI;
    
            %User inputs
            trial_tag = app.TrialtagEditField.Value;            
            manual_names_txt = app.RenamemineralsEditField.Value; %{'Cpx', 'Grt', 'Hole', 'Ol', 'Opx'};
            selected_targets_txt = app.RenamemineralsEditField_2.Value; %default= minerals_original
            selected_analysis_txt = app.GenerateListBox.Value;
            radius = app.SearchradiuspxEditField.Value; %search radius for multi-scale Association Index
            connectivity = app.ConnectivityDropDown.Value;
            resolution = app.PixelcalibrationmpxEditField.Value; %microns/pixel for GSD and stats
            sizeMax = app.TopmeshpxEditField.Value; %default=60 in pixels
            
            %defaults
            areaTH = 15; %px, threshold for grain measurements                        
            condition2 = isempty(manual_names_txt);
            condition3 = isempty(selected_targets_txt);                       
            
            % selected_analysis_txt
            selected_analysis = str2double(selected_analysis_txt);            
            condition4 = ismember(1, selected_analysis);
            condition5 = ismember(2, selected_analysis);
            condition6 = ismember(3, selected_analysis);            

            if ~condition2
                manual_names_txt1 = regexprep(manual_names_txt, '\s*', '');
                manual_names = strsplit(manual_names_txt1, ',');            
            end
            tablerank1.Mineral = manual_names';

            if ~condition3
                selected_targets_txt1 = regexprep(selected_targets_txt, '\s*', '');
                selected_targets1 = strsplit(selected_targets_txt1, ',');            
            else
                selected_targets1 = tablerank1.Mineral';           
            end
                
            %New folder
            tag_folder = fullfile(destinationDir, trial_tag);
            mkdir(tag_folder)            

            %Apply mask
            phasemap1(~ROI_mask) = 0; 
            class_map = phasemap1(pos_ROI(1):pos_ROI(3), pos_ROI(2):pos_ROI(4)); %crop            
            imwrite(ROI_mask, fullfile(tag_folder, 'roiMask.tif')); %explains selection            
            
            %ROI phase map              
            [class_map_colour, tablerank3] = phasemap_rgb(class_map, tablerank1, selected_targets1, tag_folder);
            manual_names0 = tablerank3.Mineral;
            
            writetable(tablerank3, fullfile(destinationDir, 'species_ROI.xlsx'), 'Sheet', 'phaseMap');            

            if condition4
                %Modal mineralogy
                plotLogHistogramH(tablerank3, tag_folder)
                plotLogHistogramH2(tablerank3, tag_folder)
            end
            if condition5
                %association
                [~, AIM_pct, access_map] = phasemapAIM(class_map, tablerank3, radius, connectivity, tag_folder);                 
                plotStackedH_sorted(AIM_pct, tablerank3, manual_names0, tag_folder) %named left-to-right
                %adjacency
                [adjacency_map, adjacency_rgb] = adjacencyCheck(class_map, access_map, tablerank3, tag_folder);
            end            
            if condition6
                %grain measurements
                [binary, binary_totals] = binarise_map(class_map, tablerank3);
                [sub_stats2] = grain_measurements(binary, tablerank3, areaTH, tag_folder);
                
                %granulometry (grain size distribution)
                [finer_pixels, original_pixels] = phaseGranulometry(binary, sizeMax, tag_folder); %px                
                plotGranulometry(finer_pixels, original_pixels, sizeMax, tablerank3, resolution, tag_folder) %microns
            end

        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
                        
            phasemap_colour = app.data_phasemap_colour;
            
            %Default ROI  
            dim = size(phasemap_colour);
            ROI_mask = ones(dim);
            pos_ROI = floor([1, 1, dim(1), dim(2)]);            

            app.data_ROI_mask = ROI_mask;
            app.data_pos_ROI = pos_ROI;
        end

        % Close request function: Phaseinterpreterv1UIFigure
        function Phaseinterpreterv1UIFigureCloseRequest(app, event)
            % Close all other figures when the app's main window closes
            close all; 
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Phaseinterpreterv1UIFigure = uifigure('Visible', 'off');
            app.Phaseinterpreterv1UIFigure.Name = 'Phase interpreter v1';
            app.Phaseinterpreterv1UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'puzzle.png');
            app.Phaseinterpreterv1UIFigure.CloseRequestFcn = createCallbackFcn(app, @Phaseinterpreterv1UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Phaseinterpreterv1UIFigure);
            app.DefinemapPanel = uipanel(app.GridLayout);
            app.DefinemapPanel.Title = '(1) Define map';
            app.GridLayout5 = uigridlayout(app.DefinemapPanel);
            app.GeneratepreviewButton = uibutton(app.GridLayout5, 'push');
            app.GeneratepreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepreviewButtonPushed, true);
            app.GeneratepreviewButton.Text = 'Generate preview';
            app.QuPathprojectEditFieldLabel = uilabel(app.GridLayout5);
            app.QuPathprojectEditFieldLabel.Text = 'QuPath project';
            app.QuPathprojectEditField = uieditfield(app.GridLayout5, 'text');
            app.QuPathprojectEditField.Placeholder = 'Input folder path';
            app.TrainedclassifierEditFieldLabel = uilabel(app.GridLayout5);
            app.TrainedclassifierEditFieldLabel.Text = 'Trained classifier';
            app.TrainedclassifierEditField = uieditfield(app.GridLayout5, 'text');
            app.TrainedclassifierEditField.Placeholder = 'Predicted map (classifier) basename';
            app.BackgroundclassEditFieldLabel = uilabel(app.GridLayout5);
            app.BackgroundclassEditFieldLabel.Text = 'Background class';
            app.BackgroundclassEditField = uieditfield(app.GridLayout5, 'text');
            app.BackgroundclassEditField.Placeholder = 'List classes to ignore';
            app.MirrorhorizontallyCheckBox = uicheckbox(app.GridLayout5);
            app.MirrorhorizontallyCheckBox.Text = 'Mirror horizontally';
            app.BrowseButton = uibutton(app.GridLayout5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MaprotationDropDownLabel = uilabel(app.GridLayout5);
            app.MaprotationDropDownLabel.Text = 'Map rotation';
            app.MaprotationDropDown = uidropdown(app.GridLayout5);
            app.MaprotationDropDown.Items = {'0', '90', '180', '270'};
            app.MaprotationDropDown.ItemsData = [0 90 180 270];
            app.MaprotationDropDown.Value = 90;
            app.MaskdilationDropDownLabel = uilabel(app.GridLayout5);
            app.MaskdilationDropDownLabel.Text = 'Mask dilation';
            app.MaskdilationDropDown = uidropdown(app.GridLayout5);
            app.MaskdilationDropDown.Items = {'none', '1x1 px', '10x10', '20x20', '30x30', '60x60'};
            app.MaskdilationDropDown.ItemsData = [0 1 10 20 30 60];
            app.MaskdilationDropDown.Value = 0;
            app.CustomisephasemapPanel = uipanel(app.GridLayout);
            app.CustomisephasemapPanel.Title = '(2) Customise phase map';
            app.GridLayout4 = uigridlayout(app.CustomisephasemapPanel);
            app.RenamemineralsEditField = uieditfield(app.GridLayout4, 'text');
            app.RenamemineralsEditField.Placeholder = 'List of renamed minerals (use same order)';
            app.TextArea = uitextarea(app.GridLayout4);
            app.TextArea.Placeholder = 'List of minerals for customisation';
            app.OriginalsrankedLabel = uilabel(app.GridLayout4);
            app.OriginalsrankedLabel.Text = 'Originals (ranked)';
            app.CustomnamesLabel = uilabel(app.GridLayout4);
            app.CustomnamesLabel.Text = 'Custom names';
            app.RenamemineralsEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.RenamemineralsEditField_2.Placeholder = 'List of foreground minerals';
            app.TargetsLabel = uilabel(app.GridLayout4);
            app.TargetsLabel.Text = 'Targets';
            app.DrawButton = uibutton(app.GridLayout4, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.RegionofinterestLabel = uilabel(app.GridLayout4);
            app.RegionofinterestLabel.Text = 'Region of interest';
            app.TypeofROIDropDownLabel = uilabel(app.GridLayout4);
            app.TypeofROIDropDownLabel.Text = 'Type of ROI';
            app.TypeofROIDropDown = uidropdown(app.GridLayout4);
            app.TypeofROIDropDown.Items = {'rectangle', 'circle'};
            app.TypeofROIDropDown.Value = 'rectangle';
            app.ClearROIButton = uibutton(app.GridLayout4, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Citationhttpsdoiorg103390min13020156Label = uilabel(app.GridLayout3);
            app.Citationhttpsdoiorg103390min13020156Label.Text = {'Citation: https://doi.org/10.3390/min13020156'; 'GUI update for: https://github.com/marcoaaz/Acevedo-Kamber/tree/main/QuPath_generatingMaps '};
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'QUT-Logo.png');
            app.ThegraphicaluserinterfaceisanupdateofthesoftwareLabel = uilabel(app.GridLayout2);
            app.ThegraphicaluserinterfaceisanupdateofthesoftwareLabel.Text = 'The Phase interpreter software (beta version) interface reformats a QuPath pixel classification map into a mineral phase map and allows basic image analysis (Acevedo & Kamber, 2023). The steps (1), (2), and (3) run the analysis in a selected region of interest (ROI Draw) or the whole sample (Clear ROI). Advanced users wanting custom merging phases, confusion matrix, reloading intermediate steps, etc., are encouraged to use the source code and contribute.';
            app.ChoosetheanalysisPanel = uipanel(app.GridLayout);
            app.ChoosetheanalysisPanel.Title = '(3) Choose the analysis';
            app.GridLayout6 = uigridlayout(app.ChoosetheanalysisPanel);
            app.RunButton = uibutton(app.GridLayout6, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SearchradiuspxEditFieldLabel = uilabel(app.GridLayout6);
            app.SearchradiuspxEditFieldLabel.Text = 'Search radius (px)';
            app.SearchradiuspxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SearchradiuspxEditField.Limits = [1 100];
            app.SearchradiuspxEditField.Value = 1;
            app.PixelcalibrationmpxEditFieldLabel = uilabel(app.GridLayout6);
            app.PixelcalibrationmpxEditFieldLabel.Text = 'Pixel calibration (μm/px)';
            app.PixelcalibrationmpxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PixelcalibrationmpxEditField.Limits = [0 Inf];
            app.PixelcalibrationmpxEditField.Value = 1;
            app.GenerateListBoxLabel = uilabel(app.GridLayout6);
            app.GenerateListBoxLabel.Text = 'Generate';
            app.GenerateListBox = uilistbox(app.GridLayout6);
            app.GenerateListBox.Items = {'Modal abundances', 'Association index matrix and maps', 'Grain size distribution and measurements'};
            app.GenerateListBox.ItemsData = {'1', '2', '3'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'1'};
            app.TopmeshpxEditFieldLabel = uilabel(app.GridLayout6);
            app.TopmeshpxEditFieldLabel.Text = 'Top mesh (px)';
            app.TopmeshpxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TopmeshpxEditField.Limits = [1 1000];
            app.TopmeshpxEditField.Value = 60;
            app.ConnectivityDropDownLabel = uilabel(app.GridLayout6);
            app.ConnectivityDropDownLabel.Text = 'Connectivity';
            app.ConnectivityDropDown = uidropdown(app.GridLayout6);
            app.ConnectivityDropDown.Items = {'four', 'eight'};
            app.ConnectivityDropDown.Value = 'four';
            app.TrialtagEditFieldLabel = uilabel(app.GridLayout6);
            app.TrialtagEditFieldLabel.Text = 'Trial tag';
            app.TrialtagEditField = uieditfield(app.GridLayout6, 'text');
            app.TrialtagEditField.Placeholder = 'Output folder name';
            % Show the figure after all components are created
            app.Phaseinterpreterv1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase_interpreter_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Phaseinterpreterv1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Phaseinterpreterv1UIFigure)
        end
    end
end