% description: The graphical user interface (GUI) reads a pixel-classified map and interprets the classes as minerals that can be studied (analysed) or opened elsewhere.
% Created: 20-Aug-24, Marco Acevedo Zamora
% Last update: 20-Feb-26, M.A.
% Scientific citation: Acevedo Zamora & Kamber (2023)
% Publication: https://doi.org/10.3390/min13020156
% Source code: https://github.com/marcoaaz/phase_interpreter
% Old repository: https://github.com/marcoaaz/Acevedo-Kamber/tree/main/QuPath_generatingMaps
% Future updates include a Pythonic implementation for whole-slide images.
classdef phase_interpreter_v1c < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Phaseinterpreterv1UIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ImageanalysisPanel             matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        ResultssortingDropDown         matlab.ui.control.DropDown
        ResultssortingDropDownLabel    matlab.ui.control.Label
        ConnectivityDropDown           matlab.ui.control.DropDown
        ConnectivityDropDownLabel      matlab.ui.control.Label
        ClearallButton                 matlab.ui.control.Button
        DrawROIsButton                 matlab.ui.control.Button
        RegionofinterestLabel          matlab.ui.control.Label
        BottommeshpxEditField          matlab.ui.control.NumericEditField
        BottommeshpxEditFieldLabel     matlab.ui.control.Label
        UserselectionClickCTRLSHIFTLabel  matlab.ui.control.Label
        OptionalExportformatListBox    matlab.ui.control.ListBox
        OptionalExportformatListBoxLabel  matlab.ui.control.Label
        OutputfoldertagEditField       matlab.ui.control.EditField
        OutputfoldertagEditFieldLabel  matlab.ui.control.Label
        TopmeshmEditField              matlab.ui.control.NumericEditField
        TopmeshmEditFieldLabel         matlab.ui.control.Label
        GenerateListBox                matlab.ui.control.ListBox
        GenerateLabel                  matlab.ui.control.Label
        PixelcalibrationmpxEditField   matlab.ui.control.NumericEditField
        PixelcalibrationmpxEditFieldLabel  matlab.ui.control.Label
        SearchradiuspxEditField        matlab.ui.control.NumericEditField
        SearchradiuspxEditFieldLabel   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        TextArea_2                     matlab.ui.control.TextArea
        GridLayout3                    matlab.ui.container.GridLayout
        TextArea_3                     matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        CustomisephasemapPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SelectanalysistargetsLabel     matlab.ui.control.Label
        RenamemineralsEditField_2      matlab.ui.control.EditField
        CustomnamesLabel               matlab.ui.control.Label
        OriginalsrankedLabel           matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        RenamemineralsEditField        matlab.ui.control.EditField
        DefinemapPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ProjecttypeDropDown            matlab.ui.control.DropDown
        ProjecttypeDropDownLabel       matlab.ui.control.Label
        InputpathEditField             matlab.ui.control.EditField
        InputpathEditFieldLabel        matlab.ui.control.Label
        ForegrounddilationDropDown     matlab.ui.control.DropDown
        ForegrounddilationDropDownLabel  matlab.ui.control.Label
        MaprotationDropDown            matlab.ui.control.DropDown
        MaprotationDropDownLabel       matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        MirrorhorizontallyCheckBox     matlab.ui.control.CheckBox
        BackgroundcategoriesEditField  matlab.ui.control.EditField
        BackgroundcategoriesEditFieldLabel  matlab.ui.control.Label
        GeneratepreviewButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        LastPath = pwd;
        appDir %where the app is
        data_destinationDir %where we save the files
        
        data_phasemap1 %after Preview section
        data_tablerank1
        data_phasemap_colour
        
        data_selected_targets0 %after ROI section
        data_label_map uint8
        data_ROI_data
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if isdeployed 
                % The Bio-Formats specific way to kill the console noise
                try
                    loci.common.DebugTools.setRootLevel('error'); 
                catch
                    % If the JAR isn't on the path yet, it will fail silently
                end
                
                disp('deployed')
                
                % Get the folder where the .exe is running               
                appDir = ctfroot; % Alternative way to find the runtime root
                % appDir = pwd; %to save a configuration file or a log that stays there after the app closes

                app.appDir = appDir;
                app.InputpathEditField.Value = 'H:\Collaborations\Balz-Rodrigo-Matthew collab\rodrigo work\UHP32_benchmark\registration_11-Apr-24\registered\8bit\stack\qupath trial_14-Oct-25\run_s10-hrMaps.ome.tif';
                app.BackgroundcategoriesEditField.Value = 'hole';

            else %.mlapp run
                disp('not deployed')

                %Dependencies
                scriptsMarco = 'E:\Alienware_March 22\scripts_Marco\updated MatLab scripts\';            
                script_path = fullfile(scriptsMarco, 'quPath bridge');
                
                addpath(script_path);
                addpath(fullfile(script_path, 'functions_main'));
                addpath(fullfile(script_path, 'functions_plot'));
                addpath(fullfile(script_path, 'extension_TIMA'));
                addpath(fullfile(script_path, 'extension_iolite'));
                addpath(fullfile(script_path, 'extension_geopixe'));
                addpath(fullfile(scriptsMarco, 'bfmatlab'));
                addpath(fullfile(scriptsMarco, 'external_package'));
                addpath(fullfile(scriptsMarco, 'external_package\rgb'));
                
                appDir = fileparts(which(mfilename)); 

                % Initialize app properties  
                app.appDir = appDir;
                app.InputpathEditField.Value = 'H:\Collaborations\Balz-Rodrigo-Matthew collab\rodrigo work\UHP32_benchmark\registration_11-Apr-24\registered\8bit\stack\qupath trial_14-Oct-25\run_s10-hrMaps.ome.tif'; %*.ome.tif saved from QuPath
                app.BackgroundcategoriesEditField.Value = 'hole';

            end          
            
            format compact %displaying list

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            LastPath1 = app.LastPath;

            % Define the file filters (folders are always visible in this dialog)
            fileFilters = { ...
                '*.tif', 'OME-TIF Files (*.ome.tif)'; ...
                '*.*',          'All Files (*.*)'};
        
            % Open the selection dialog
            [file, path] = uigetfile(fileFilters, 'Select a TIF File or Folder', LastPath1); 
        
            % Check if the user made a selection
            if ischar(file)

                app.LastPath = path;
                
                % Create the full path string
                fullSelection = fullfile(path, file);
                
                % Update your app's edit field
                app.InputpathEditField.Value = string(fullSelection);
            else
                % User pressed 'Cancel'
                disp('Selection cancelled.');
            end
        end

        % Button pushed function: GeneratepreviewButton
        function GeneratepreviewButtonPushed(app, event)
            
            %-------Create a modal progress dialog (this "freezes" the app)            
            d = uiprogressdlg(app.Phaseinterpreterv1UIFigure, 'Title', 'Please Wait', ...
                'Message', 'Processing your data...', 'Indeterminate', 'on');          
            drawnow;

            %User input:
            input_files = app.InputpathEditField.Value; %*.ome.tif saved from QuPath
            input_type = app.ProjecttypeDropDown.Value;
            class_bg_txt = app.BackgroundcategoriesEditField.Value; %classes to ignore, if not found (uses all)
            kernel_size = app.ForegrounddilationDropDown.Value;
            rot_angle = app.MaprotationDropDown.Value; %rotation angle (counter-clockwise)
            action_mirror = app.MirrorhorizontallyCheckBox.Value; %horizontal flip                                    
                                 
            %defaults                     
            class_bg = {""};
            condition1 = isempty(class_bg_txt); %0x0 char                        
            
            if ~condition1
                class_bg_txt1 = regexprep(class_bg_txt, '\s*', '');
                class_bg = strsplit(class_bg_txt1, ',');
            end                         
            
            %read maps
            if input_type == 1 %qupath
                [rootFolder, classifierName0, ~] = fileparts(input_files);
                classifierName = strrep(classifierName0, '.ome', '');
                
                [minerals, triplet, destinationDir] = read_qupath_metadata(classifierName, rootFolder);
                [phasemap_fg, section_mask, tablerank] = read_qupath_map(classifierName, class_bg, ...
                    minerals, triplet, kernel_size, destinationDir);
            
            elseif input_type == 2 %'tima'
                plotOption = 0;
                [montage_Info, table_mineral2, destinationDir] = metadata_MinDIF(input_files, plotOption);
                
                [phasemap_fg, section_mask, tablerank] = read_MinDIF_map(montage_Info, table_mineral2,...
                    class_bg, kernel_size);
            
            end         

            [phasemap, section_mask1] = transform_map(phasemap_fg, section_mask, rot_angle, action_mirror);
            imwrite(section_mask1, fullfile(destinationDir, 'sectionMask.tif')); %explains selection

            [tablerank1, phasemap1] = edit_phasemap(phasemap, tablerank, destinationDir);
            
            %Generate preview
            tablerank1 = readtable(fullfile(destinationDir, 'species.xlsx'), 'Sheet', 'phaseMap');
            selected_targets0 = tablerank1.Mineral';               
            
            [phasemap_colour, tablerank2] = phasemap_rgb(phasemap1, tablerank1, "", "selection", destinationDir);
            plot_phasemap(phasemap_colour, tablerank2, destinationDir)            
            plotLogHistogramH(tablerank2, destinationDir)
            plotLogHistogramH2(tablerank2, destinationDir)

            %Default ROI (same as Clear ROI)                          
            rows = size(phasemap_colour, 1);
            cols = size(phasemap_colour, 2);
            label_map = ones(rows, cols, 'uint8'); %default 

            %Send through app
            app.data_destinationDir = destinationDir;
            app.data_phasemap1 = phasemap1;
            app.data_tablerank1 = tablerank1;
            app.data_phasemap_colour = phasemap_colour;
            app.TextArea.Value = strjoin(selected_targets0, ', ');
            app.RenamemineralsEditField.Value = strjoin(selected_targets0, ', '); %for practicity
            app.data_label_map = label_map; 
            app.data_ROI_data = [];

            % --- Close the dialog when finished ---
            close(d);

        end

        % Button pushed function: DrawROIsButton
        function DrawROIsButtonPushed(app, event)
                        
            phasemap_colour = app.data_phasemap_colour;           
            destinationDir = app.data_destinationDir;
            trial_tag = app.OutputfoldertagEditField.Value;  

            tag_folder = fullfile(destinationDir, trial_tag);            
            if ~exist(tag_folder, 'dir')
                mkdir(tag_folder);
            end            

            %Draw ROIs 
            [label_map, ROI_data] = mapping_ROI_manager(phasemap_colour, tag_folder);

            app.data_label_map = label_map; 
            app.data_ROI_data = ROI_data;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            close all %previous plots
            
            %-------Create a modal progress dialog (this "freezes" the app)            
            d = uiprogressdlg(app.Phaseinterpreterv1UIFigure, 'Title', 'Please Wait', ...
                'Message', 'Processing your data...', 'Indeterminate', 'on');          
            drawnow;

            %Data from app
            destinationDir = app.data_destinationDir;
            phasemap1 = app.data_phasemap1;
            tablerank1 = app.data_tablerank1;
            label_map = app.data_label_map;
            % ROI_data = app.data_ROI_data;

            %User inputs
            trial_tag = app.OutputfoldertagEditField.Value;  
            sorting_type0 = app.ResultssortingDropDown.Value;
            manual_names_txt = app.RenamemineralsEditField.Value; %{'Cpx', 'Grt', 'Hole', 'Ol', 'Opx'};
            selected_targets_txt = app.RenamemineralsEditField_2.Value; %default= minerals_original
            selected_analysis_txt = app.GenerateListBox.Value;
            selected_export = app.OptionalExportformatListBox.Value;
            radius = app.SearchradiuspxEditField.Value; %search radius for multi-scale Association Index
            connectivity = app.ConnectivityDropDown.Value;
            resolution = app.PixelcalibrationmpxEditField.Value; %microns/pixel for GSD and stats
            sizeMax = app.TopmeshmEditField.Value; %default=60 microns, threshold for grain measurements 
            areaTH = app.BottommeshpxEditField.Value; %default= 15 px, 

            %defaults   
            rows = size(label_map, 1);
            cols = size(label_map, 2);    

            tag_folder = fullfile(destinationDir, trial_tag);
            if ~exist(tag_folder, 'dir')
                mkdir(tag_folder);
            end
            winopen(tag_folder)

            if sorting_type0 == 1
                sorting_type = 'selection';
            elseif sorting_type0 == 2
                sorting_type = 'non-zero';
            elseif sorting_type0 == 3
                sorting_type = 'ranked';
            else
                sprintf('%s not supported', sorting_type0)
            end                       
            
            condition2 = isempty(manual_names_txt);
            if ~condition2
                manual_names_txt1 = regexprep(manual_names_txt, '\s*', '');
                manual_names = strsplit(manual_names_txt1, ',');            
            end
            tablerank1.Mineral = manual_names';
            
            condition3 = isempty(selected_targets_txt);                       
            if ~condition3
                selected_targets_txt1 = regexprep(selected_targets_txt, '\s*', '');
                selected_targets = strsplit(selected_targets_txt1, ',');            
            else
                selected_targets = tablerank1.Mineral';           
            end       
                                    
            selected_analysis = str2double(selected_analysis_txt);            
            condition4 = ismember(1, selected_analysis);
            condition5 = ismember(2, selected_analysis);
            condition6 = ismember(3, selected_analysis); 
            condition7 = ismember(4, selected_analysis);
            sizeMax_px = floor(sizeMax/resolution); %default=60 px
            
            %geopixe                      
            condition8 = ismember(1, selected_export);           
            
            %Loop
            num_labels = max(label_map(:));
            for L = 1:num_labels
            
                %Prepare Folders    
                label_tag = sprintf('label_%03d', L);
                label_folder = fullfile(tag_folder, label_tag);
                if ~exist(label_folder, 'dir')
                    mkdir(label_folder);
                end
            
                %Create mask for the current label
                ROI_mask = (label_map == L);
                if ~any(ROI_mask(:)), continue; end
                imwrite(logical(ROI_mask), fullfile(label_folder, 'roiMask.tif')); %explains selection (>4GB writing issue)
                
                %Bounding Box                
                stats = regionprops(ROI_mask, 'BoundingBox');
                bbox = stats.BoundingBox; % [x_min, y_min, width, height]
                                
                row_start = max(1, floor(bbox(2)));
                row_end   = min(rows, ceil(bbox(2) + bbox(4)));
                col_start = max(1, floor(bbox(1)));
                col_end   = min(cols, ceil(bbox(1) + bbox(3)));     
                
                %Apply Mask and Crop                
                phasemap_masked = phasemap1;
                phasemap_masked(~ROI_mask) = 0; 
                class_map = phasemap_masked(row_start:row_end, col_start:col_end);        
                
            
                % --- Analysis Functions ---
                
                % ROI phase map              
                [class_map_colour, tablerank3] = phasemap_rgb(class_map, tablerank1, selected_targets, sorting_type, label_folder);
                manual_names0 = tablerank3.Mineral;
                
                
                writetable(tablerank3, fullfile(label_folder, 'species.xlsx'), 'Sheet', 'phaseMap');            
                
                if condition4
                    % Modal mineralogy
                    plotLogHistogramH(tablerank3, label_folder)
                    plotLogHistogramH2(tablerank3, label_folder)
                end
                
                if condition5
                    % Mineral association
                    [~, AIM_pct, access_map] = phasemapAIM_fast(class_map, tablerank3, radius, connectivity, label_folder);                
                    plotStackedH_sorted(AIM_pct, tablerank3, manual_names0, label_folder) 
                    
                    % Adjacency
                    [adjacency_map] = adjacencyCheck_fast(class_map, access_map, tablerank3, label_folder);
                end
                
                if condition6
                    % Grain measurements
                    [binary, ~] = binarise_map(class_map, tablerank3);                                
                    [sub_stats2] = grain_measurements_RAM(binary, tablerank3, areaTH, label_folder);
                    
                    % Granulometry
                    [finer_pixels, original_pixels] = phaseGranulometryFast(binary, sizeMax_px, label_folder);
                    plotGranulometry(finer_pixels, original_pixels, sizeMax_px, tablerank3, resolution, label_folder) 
                end                
              
                if condition7 && condition8
                    geopixeDir = fullfile(label_folder, 'geopixe_Qvectors');
                    if ~exist(geopixeDir, 'dir')
                        mkdir(geopixeDir);
                    end  
                    % Note: Using the original phasemap to keep Q-vectors relative to
                    % XFM coordinate origin
                    [~, tablerank4] = phasemap_rgb(phasemap_masked, tablerank1, selected_targets, sorting_type, geopixeDir);
                    imgLabel = imread(fullfile(geopixeDir, 'phasemap_target.tif'));
                    dim = size(imgLabel);
                    
                    [indList] = indWithinMAP_pro(imgLabel, tablerank4);
                    geopixe_export_all(tablerank4, indList, dim, geopixeDir)
                    geopixe_export_merged(tablerank4, selected_targets, indList, dim, geopixeDir)
                end
    
                fprintf('Ready: Label %d of %d\n', L, num_labels);              
                
            end

            close(d);

        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
                        
            phasemap_colour = app.data_phasemap_colour;
            
            %Default ROI  
            rows = size(phasemap_colour, 1);
            cols = size(phasemap_colour, 2);
            label_map = ones(rows, cols, 'uint8'); %default                    

            app.data_label_map = label_map; 
            app.data_ROI_data = [];

        end

        % Close request function: Phaseinterpreterv1UIFigure
        function Phaseinterpreterv1UIFigureCloseRequest(app, event)
            % Close all other figures when the app's main window closes
            close all; 
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Phaseinterpreterv1UIFigure = uifigure('Visible', 'off');
            app.Phaseinterpreterv1UIFigure.Name = 'Phase interpreter v1';
            app.Phaseinterpreterv1UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'puzzle.png');
            app.Phaseinterpreterv1UIFigure.CloseRequestFcn = createCallbackFcn(app, @Phaseinterpreterv1UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Phaseinterpreterv1UIFigure);
            app.DefinemapPanel = uipanel(app.GridLayout);
            app.DefinemapPanel.Title = '(1) Define map';
            app.GridLayout5 = uigridlayout(app.DefinemapPanel);
            app.GeneratepreviewButton = uibutton(app.GridLayout5, 'push');
            app.GeneratepreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepreviewButtonPushed, true);
            app.GeneratepreviewButton.Text = 'Generate preview';
            app.BackgroundcategoriesEditFieldLabel = uilabel(app.GridLayout5);
            app.BackgroundcategoriesEditFieldLabel.Text = 'Background categories';
            app.BackgroundcategoriesEditField = uieditfield(app.GridLayout5, 'text');
            app.BackgroundcategoriesEditField.Placeholder = 'List classes to ignore';
            app.MirrorhorizontallyCheckBox = uicheckbox(app.GridLayout5);
            app.MirrorhorizontallyCheckBox.Text = 'Mirror horizontally';
            app.BrowseButton = uibutton(app.GridLayout5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MaprotationDropDownLabel = uilabel(app.GridLayout5);
            app.MaprotationDropDownLabel.Text = 'Map rotation';
            app.MaprotationDropDown = uidropdown(app.GridLayout5);
            app.MaprotationDropDown.Items = {'0', '90', '180', '270'};
            app.MaprotationDropDown.ItemsData = [0 90 180 270];
            app.MaprotationDropDown.Value = 90;
            app.ForegrounddilationDropDownLabel = uilabel(app.GridLayout5);
            app.ForegrounddilationDropDownLabel.Text = 'Foreground dilation';
            app.ForegrounddilationDropDown = uidropdown(app.GridLayout5);
            app.ForegrounddilationDropDown.Items = {'none', '1x1 px', '10x10', '20x20', '30x30', '60x60'};
            app.ForegrounddilationDropDown.ItemsData = [0 1 10 20 30 60];
            app.ForegrounddilationDropDown.Value = 0;
            app.InputpathEditFieldLabel = uilabel(app.GridLayout5);
            app.InputpathEditFieldLabel.Text = 'Input path';
            app.InputpathEditField = uieditfield(app.GridLayout5, 'text');
            app.InputpathEditField.Placeholder = 'Specific path';
            app.ProjecttypeDropDownLabel = uilabel(app.GridLayout5);
            app.ProjecttypeDropDownLabel.Text = 'Project type';
            app.ProjecttypeDropDown = uidropdown(app.GridLayout5);
            app.ProjecttypeDropDown.Items = {'QuPath v0.6', 'TIMA (Tescan)'};
            app.ProjecttypeDropDown.ItemsData = [1 2];
            app.ProjecttypeDropDown.Value = 1;
            app.CustomisephasemapPanel = uipanel(app.GridLayout);
            app.CustomisephasemapPanel.Title = '(2) Customise phase map';
            app.GridLayout4 = uigridlayout(app.CustomisephasemapPanel);
            app.RenamemineralsEditField = uieditfield(app.GridLayout4, 'text');
            app.RenamemineralsEditField.Placeholder = 'List of renamed minerals (use same order)';
            app.TextArea = uitextarea(app.GridLayout4);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'List of phases for customisation';
            app.OriginalsrankedLabel = uilabel(app.GridLayout4);
            app.OriginalsrankedLabel.Text = 'Originals (ranked)';
            app.CustomnamesLabel = uilabel(app.GridLayout4);
            app.CustomnamesLabel.Text = 'Custom names';
            app.RenamemineralsEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.RenamemineralsEditField_2.Placeholder = 'List of foreground minerals (use custom names)';
            app.SelectanalysistargetsLabel = uilabel(app.GridLayout4);
            app.SelectanalysistargetsLabel.Text = 'Select analysis targets';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'QUT-Logo.png');
            app.TextArea_3 = uitextarea(app.GridLayout3);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Citations: https://doi.org/10.3390/min13020156 ; https://doi.org/10.3390/min15040355'; 'Source code: https://github.com/marcoaaz/phase_interpreter'};
            app.TextArea_2 = uitextarea(app.GridLayout2);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Convert a pixel classified map into a mineral phase map (Acevedo & Kamber, 2023) for image analysis (step 3). The steps (2), and (3) run the analysis in a selected ROI (Draw button) or the whole sample (Clear button). Further MatLab prototype scripts are available for merging phases, confusion matrix, reloading intermediate steps, or interacting with other instrument software (TIMA, GeoPIXE, Iolite). See source code extensions.'};
            app.ImageanalysisPanel = uipanel(app.GridLayout);
            app.ImageanalysisPanel.Title = '(3) Image analysis';
            app.GridLayout6 = uigridlayout(app.ImageanalysisPanel);
            app.RunButton = uibutton(app.GridLayout6, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SearchradiuspxEditFieldLabel = uilabel(app.GridLayout6);
            app.SearchradiuspxEditFieldLabel.Text = 'Search radius (px)';
            app.SearchradiuspxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SearchradiuspxEditField.Limits = [1 100];
            app.SearchradiuspxEditField.Value = 1;
            app.PixelcalibrationmpxEditFieldLabel = uilabel(app.GridLayout6);
            app.PixelcalibrationmpxEditFieldLabel.Text = 'Pixel calibration (μm/px)';
            app.PixelcalibrationmpxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PixelcalibrationmpxEditField.Limits = [0 Inf];
            app.PixelcalibrationmpxEditField.Value = 1;
            app.GenerateLabel = uilabel(app.GridLayout6);
            app.GenerateLabel.Text = 'Generate:';
            app.GenerateListBox = uilistbox(app.GridLayout6);
            app.GenerateListBox.Items = {'Targets map and modal abundances', 'Association index matrix and accessibility maps', 'Grain measurements and size distribution', 'Mask export'};
            app.GenerateListBox.ItemsData = {'1', '2', '3', '4'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'1'};
            app.TopmeshmEditFieldLabel = uilabel(app.GridLayout6);
            app.TopmeshmEditFieldLabel.Text = 'Top mesh (μm)';
            app.TopmeshmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TopmeshmEditField.Limits = [1 1000];
            app.TopmeshmEditField.Value = 60;
            app.OutputfoldertagEditFieldLabel = uilabel(app.GridLayout6);
            app.OutputfoldertagEditFieldLabel.Text = 'Output folder tag';
            app.OutputfoldertagEditField = uieditfield(app.GridLayout6, 'text');
            app.OutputfoldertagEditField.Placeholder = 'Trial folder name';
            app.OutputfoldertagEditField.Value = 'trial_1';
            app.OptionalExportformatListBoxLabel = uilabel(app.GridLayout6);
            app.OptionalExportformatListBoxLabel.Text = 'Optional: Export format';
            app.OptionalExportformatListBox = uilistbox(app.GridLayout6);
            app.OptionalExportformatListBox.Items = {'GeoPIXE software (XFM)'};
            app.OptionalExportformatListBox.ItemsData = 1;
            app.OptionalExportformatListBox.Value = 1;
            app.UserselectionClickCTRLSHIFTLabel = uilabel(app.GridLayout6);
            app.UserselectionClickCTRLSHIFTLabel.Text = {'User selection '; '(Click + CTRL/SHIFT)'};
            app.BottommeshpxEditFieldLabel = uilabel(app.GridLayout6);
            app.BottommeshpxEditFieldLabel.Text = 'Bottom mesh (px)';
            app.BottommeshpxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.BottommeshpxEditField.Limits = [1 1000];
            app.BottommeshpxEditField.Value = 15;
            app.RegionofinterestLabel = uilabel(app.GridLayout6);
            app.RegionofinterestLabel.Text = 'Region of interest';
            app.DrawROIsButton = uibutton(app.GridLayout6, 'push');
            app.DrawROIsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIsButtonPushed, true);
            app.DrawROIsButton.Text = 'Draw ROIs';
            app.ClearallButton = uibutton(app.GridLayout6, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ConnectivityDropDownLabel = uilabel(app.GridLayout6);
            app.ConnectivityDropDownLabel.Text = 'Connectivity';
            app.ConnectivityDropDown = uidropdown(app.GridLayout6);
            app.ConnectivityDropDown.Items = {'four', 'eight'};
            app.ConnectivityDropDown.Value = 'four';
            app.ResultssortingDropDownLabel = uilabel(app.GridLayout6);
            app.ResultssortingDropDownLabel.Text = 'Results sorting';
            app.ResultssortingDropDown = uidropdown(app.GridLayout6);
            app.ResultssortingDropDown.Items = {'Selection', 'Non-zero selection', 'Ranked'};
            app.ResultssortingDropDown.ItemsData = [1 2 3];
            app.ResultssortingDropDown.Value = 2;
            % Show the figure after all components are created
            app.Phaseinterpreterv1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase_interpreter_v1c

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Phaseinterpreterv1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Phaseinterpreterv1UIFigure)
        end
    end
end